classdef Content_based_Image_Retrieval < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        ContentbasedImageRetrievalUIFigure  matlab.ui.Figure
        retrievalBtn                 matlab.ui.control.Button
        Image                        matlab.ui.control.Image
        TrainningModelPanel          matlab.ui.container.Panel
        ModelTextAreaLabel           matlab.ui.control.Label
        ModelTextArea                matlab.ui.control.TextArea
        modelSelectorBtn             matlab.ui.control.Button
        DatasetPanel                 matlab.ui.container.Panel
        DatasetTextAreaLabel         matlab.ui.control.Label
        DatasetTextArea              matlab.ui.control.TextArea
        dataSelectorBtn              matlab.ui.control.Button
        extractionLamp               matlab.ui.control.Lamp
        extractionBtn                matlab.ui.control.Button
        extractionLabel              matlab.ui.control.Label
        DimensionofthefeaturesSwitchLabel  matlab.ui.control.Label
        dimensionSwitch              matlab.ui.control.Switch
        ImageForQueryLabel           matlab.ui.control.Label
        QuestionBeforeSelectingTheDatasetPanel  matlab.ui.container.Panel
        matFileCheckBox              matlab.ui.control.CheckBox
        compileCheckBox              matlab.ui.control.CheckBox
        ResultPanel                  matlab.ui.container.Panel
        promptLabel                  matlab.ui.control.Label
        ImageRank1                   matlab.ui.control.Image
        ImageRank2                   matlab.ui.control.Image
        ImageRank3                   matlab.ui.control.Image
        Rank1Label                   matlab.ui.control.Label
        Rank2Label                   matlab.ui.control.Label
        Rank3Label                   matlab.ui.control.Label
        TimeLabel                    matlab.ui.control.Label
        timeTextArea                 matlab.ui.control.TextArea
        RetrievalNumberSpinnerLabel  matlab.ui.control.Label
        retrievalNumberSpinner       matlab.ui.control.Spinner
        JianmingLaoLabel             matlab.ui.control.Label
        mAPTextArea                  matlab.ui.control.TextArea
        ThemAPofDropDownLabel        matlab.ui.control.Label
        mAPDropDown                  matlab.ui.control.DropDown
        CalculateButton              matlab.ui.control.Button
    end

    
    properties (Access = private)
        modelPath % 训练模型路径
        datasetPath % 数据集路径
        filePath % 数据文件路径
        setupFlag = 0 % 记录matconvnet工具箱是否安装
        mAPModelName % 记录要测试mAP的数据集
        resultName % 记录所有需查询图像结果存放文件名
        oxfordGT = './Data/gnd_Oxford.mat' % 记录计算oxford的mAP时的Groundtruth文件名
        pathNeeded % 记录计算mAP需添加的图像文件夹
    end
    
    methods (Access = private)
        
        function results = getResultFileHolidays(app) % 统一格式输出Holidays检索结果
            dataset = load(app.mAPModelName);
            addpath(app.pathNeeded);
            retrievalNum = 10;  % 注意包含了本身
            resultFile = {};
            line = 1;
            for i = 1 : size(dataset.nameList, 1)
                queryImageName = split(dataset.nameList{i, 1}, '.');  % 除去'.jpg'，获得真实名字
                if mod(str2double(queryImageName{1}), 100) == 0  % 只有能被100整除的才是需要计算的
                    resultLine = [queryImageName{1}, '.jpg'];  % 统一格式
                    [n,~] = size(dataset.featureNorm);
                    score = zeros(n, 1);
                    score = (dataset.featureNorm(i, :) * dataset.featureNorm')';  % 向量内积
                    [~, rankedImageID] = sort(score, 'descend');  % 排序
                    for k = 2 : retrievalNum  % 除去自己本身
                        rank = k - 2;  % rank序号从0开始
                        queryResult = [num2str(rank), ' ', dataset.nameList{rankedImageID(k, 1), 1}]; % 统一格式
                        temp = [resultLine, ' ', queryResult];
                        resultLine = temp;
                    end
                    resultFile{line, 1} = resultLine;
                    line = line + 1;
                end
            end
            
            dlmwrite(app.resultName, '');
            for i = 1 : size(resultFile, 1)
                dlmwrite(app.resultName, resultFile{i,1}, 'delimiter', '', 'newline', 'pc', '-append');
            end
            
        end
        
        function results = getResultFileOxford(app) % 统一格式输出Oxford检索结果
            dataset = load(app.mAPModelName);
            gt = load(app.oxfordGT);
            addpath(app.pathNeeded);
            retrievalNum = 100;  % 注意包含了本身
            resultFile = {};
            line = 1;
            for i = 1 : size(gt.qidx, 1)
                queryImageName = gt.imlist{gt.qidx(i)};  % 获得查询图像
                queryImageName = [queryImageName, '.jpg'];
                orgIndex = 0;
                for j = 1 : size(dataset.featureNorm)
                    if isequal(queryImageName, dataset.nameList{j, 1})
                        orgIndex = j;
                        break;
                    end
                end
                resultLine = queryImageName;  % 统一格式
                [n,~] = size(dataset.featureNorm);
                score = zeros(n, 1);
                score = (dataset.featureNorm(orgIndex, :) * dataset.featureNorm')';  % 向量内积
                [~, rankedImageID] = sort(score, 'descend');  % 排序
                for k = 2 : retrievalNum  % 除去自己本身
                    rank = k - 2;  % rank序号从0开始
                    queryResult = [num2str(rank), ' ', dataset.nameList{rankedImageID(k, 1), 1}];
                    temp = [resultLine, ' ', queryResult];
                    resultLine = temp;
                end
                resultFile{line, 1} = resultLine;
                line = line + 1;
            end
            
            dlmwrite(app.resultName, '');
            for i = 1 : size(resultFile, 1)
                dlmwrite(app.resultName, resultFile{i,1}, 'delimiter', '', 'newline', 'pc', '-append');
            end
            
        end
        
        function mAPPercentage = calMAPHolidays(app) % 计算Holidays数据集mAP
            %% 读取检索结果和图片名字
            queryFile = importdata(app.resultName);
            allFiles = importdata('./Data/holidays_images.dat');
            gt = cell(500, 2);
            k = 0;
            
            %% 根据官网指南纪录groundtruth信息
            for i = 1 : size(allFiles, 1)
                name = allFiles{i, 1};
                nameNum = str2double(split(name, '.'));
                if mod(nameNum(1), 100) == 0  % 是查询图像，则其与下一个查询图像之间的图像都是相似图像
                    k = k + 1;
                    gt{k, 1} = name;
                    gt{k, 2} = {};
                else
                    temp = [gt{k, 2}, {name}];  % 下一个查询图像之间之后的图像都是相关图像
                    gt{k, 2} = temp;
                end
            end
            
            %% 计算所有查询图像ap和
            apSum = 0;
            for i = 1 : size(queryFile, 1)
                currentLine = split(queryFile{i}, ' ');
                queryName = currentLine{1};
                results = currentLine(2 : end);
                
                for j = 1 : size(gt, 1)  % 在gt中找到当前图像，得到其相似图像
                    if isequal(queryName, gt{j, 1})
                        gtResult = gt{j, 2};
                        break
                    end
                end
                
                % 记录检索结果中出现的相似图像
                ranks = [];
                for j = 1 : size(results, 1) / 2
                    rank = results(j * 2 - 1);
                    rankName = results(j * 2);
                    if ismember(rankName, gtResult)
                        ranks = [ranks, str2double(rank{1})];
                    end
                end
                
                % 计算ap，即PR曲线下的面积，这里近似梯形，有一定误差
                ap = 0;
                resultsNum = size(gtResult, 2);
                recallStep = 1.0 / resultsNum;  % x轴步长
                for j = 1 : size(ranks, 2)
                    rank = ranks(j);
                    ntp = j - 1;  % 真正类，查找正确
                    if rank == 0
                        precision0 = 1.0;
                    else
                        precision0 = ntp / rank;
                    end
                    precision1 = (ntp + 1) / (rank + 1);  % 梯形右侧长
                    ap = ap + (precision1 + precision0) * recallStep / 2.0;  % 梯形上底加下底 * 高 / 2
                end
                apSum = apSum + ap;
            end
            mAP = apSum / size(queryFile, 1);
            mAPPercentage = mAP * 100;
        end
        
        function mAPPercentage = calMAPOxford(app) % 计算Oxford数据集mAP
            %% 读取检索结果和图片名字
            queryFile = importdata(app.resultName);
            gt = load(app.oxfordGT);
            %% 计算所有查询图像ap和
            apSum = 0;
            for i = 1 : size(queryFile, 1)
                currentLine = split(queryFile{i}, ' ');
                queryName = currentLine{1};
                results = currentLine(2 : end);  % 获得排序后的前三幅图
                
                ranks = [];
                for j = 1 : size(results, 1) / 2
                    rankName = results(j * 2);
                    rankName = split(rankName, '.');
                    for k = 1 : size(gt.imlist, 1)  % 找到三幅图像对应的图片下标
                        if isequal(rankName{1}, gt.imlist{k})
                            ranks = [ranks, k];
                            break;
                        end
                    end
                end
                
                % 根据Oxford数据官网给出的计算ap的C++代码改写
                ap = 0.0;
                oldRecall = 0.0;
                oldPrecision = 1.0;
                intersectSize = 0;
                k = 0;
                for j = 1 : size(ranks, 2)
                    if ismember(ranks(j), gt.gnd(i).junk)  % 是无关图像，不做运算
                        continue;
                    end
                    if ismember(ranks(j), gt.gnd(i).ok)
                        intersectSize = intersectSize + 1;
                    end
                    
                    recall = intersectSize / size(gt.gnd(i).ok, 2);
                    precision = intersectSize / (k + 1.0);
                    
                    ap = ap + (recall - oldRecall) * ((oldPrecision + precision) / 2.0);
                    
                    oldRecall = recall;
                    oldPrecision = precision;
                    k = k + 1;
                end
                apSum = apSum + ap;
            end
            mAP = apSum / size(queryFile, 1);
            mAPPercentage = mAP * 100;
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: modelSelectorBtn
        function modelSelectorBtnPushed(app, event)
            % 功能：选择训练模型，可直接matconvnet官方VGG训练模型'imagenet-vgg-f.mat'
            [file, path, index] = uigetfile({'*.mat';'*.*'},'Training Model Selector');
            if (file) & (index == 1)
                app.modelPath = strcat(path, file);  % 训练模型完整路径
                app.ModelTextArea.Value = file;
                app.dataSelectorBtn.Enable = 1;
            elseif index ~= 1
                uialert(app.ContentbasedImageRetrievalUIFigure, 'Please choose the mat file!','Invalid File');
            end
        end

        % Button pushed function: dataSelectorBtn
        function dataSelectorBtnButtonPushed(app, event)
            % 功能：选择数据集
            if isequal(app.matFileCheckBox.Value, 0)  % 如果用户要选择的数据集不是mat类型
                path = uigetdir('Select the dataset');  % 那么其只能是数据存放的文件夹
                if path
                    app.filePath = path;
                    pathVec = regexp(path, '\\', 'split');
                    pathName = pathVec{end};
                    app.DatasetTextArea.Value = pathName;
                    app.extractionBtn.Enable = 1;
                    % 提示用户需要对文件夹中图像提取特征向量
                    app.extractionLabel.Text = 'Extract features first.';
                    app.extractionLamp.Color = [1.00, 0.00, 0.00];
                end
            else
                [file, path, index] = uigetfile({'*.mat'},'Model Selector', './Data/');
                if (file) & (index == 1)  % 必须选择的是有效的mat文件
                    app.datasetPath = strcat(path, file);
                    app.DatasetTextArea.Value = file;
                    app.retrievalBtn.Enable = 1;
                    app.mAPTextArea.Value = "";
                    if isequal(size(strfind(file, '128'), 1), 0)
                        app.dimensionSwitch.Value = app.dimensionSwitch.Items{1};
                        dimensionSwitchValueChanged(app, event);
                    else
                        app.dimensionSwitch.Value = app.dimensionSwitch.Items{2};
                        dimensionSwitchValueChanged(app, event);
                    end
                    % 提示用户无需对图像提取特征向量
                    app.extractionLabel.Text = 'No need for extraction.';
                    app.extractionLamp.Color = [0.00, 1.00, 0.00];
                    % 如果用户机器中matconvnet未配置，则要先配置
                    if isequal(app.compileCheckBox.Value, 0)
                        progressWindow = uiprogressdlg(app.ContentbasedImageRetrievalUIFigure, 'Title','Compiling the net',...
                            'Message','Just a few seconds...',...
                            'Indeterminate','on');
                        run ./matconvnet-1.0-beta25/matlab/vl_compilenn;
                        close(progressWindow);
                    end
                end
            end
        end

        % Button pushed function: retrievalBtn
        function retrievalBtnButtonPushed(app, event)
            % 功能：检索图像
            database = load(app.datasetPath);
            if isequal(app.matFileCheckBox.Value, 0)
                addpath(app.filePath);
            end
            if isequal(app.setupFlag, 0)  % 如果用户机器中cnn为搭建，则搭建
                run ./matconvnet-1.0-beta25/matlab/vl_setupnn;
                app.setupFlag = 1;
            end
            retrievalNum = app.retrievalNumberSpinner.Value;  % 检索返回图像数量
            if strfind(app.DatasetTextArea.Value{1}, 'Holidays') == 1  % 检索的是Holidays数据集
                oriPath = './Holidays/';
                if isequal(app.dimensionSwitch.Value, app.dimensionSwitch.Items{2})
                    app.mAPDropDown.Value =  app.mAPDropDown.Items{2};
                else
                    app.mAPDropDown.Value =  app.mAPDropDown.Items{1};
                end
            elseif strfind(app.DatasetTextArea.Value{1}, 'Oxford') == 1  % 检索的是Oxford5k数据集
                oriPath = './Oxford5k/';
                if isequal(app.dimensionSwitch.Value, app.dimensionSwitch.Items{2})
                    app.mAPDropDown.Value =  app.mAPDropDown.Items{4};
                else
                    app.mAPDropDown.Value =  app.mAPDropDown.Items{3};
                end
            else  % 检索的是新数据集
                if app.matFileCheckBox.Value == 0
                    oriPath = app.filePath;
                else
                    oriPath = pwd;
                end
            end
            [file,path] = uigetfile({'*.jpg';'*.png';'*.bmp';'*.*'},'Picture Selector', oriPath);
            app.promptLabel.Enable = 0;
            app.ImageRank1.Visible = 1;
            app.ImageRank2.Visible = 1;
            app.ImageRank3.Visible = 1;
            app.Image.ImageSource = "";
            app.ImageRank1.ImageSource = "";
            app.ImageRank2.ImageSource = "";
            app.ImageRank3.ImageSource = "";
            app.timeTextArea.Value = "";
            if file
                tic;
                addpath(oriPath);
                orgIndex = 0;
                wholePath = strcat(path,file);  % 待检索图片路径
                oriImg = imread(wholePath);
                imgW = 500;  % 控制图像规格统一
                imgL = 500;
                imgQuery = imresize(oriImg, [imgL imgW]);  % 图像规格，可自行调整
                app.Image.ImageSource = imgQuery;  % 展示待检索图片
                if isequal(app.dimensionSwitch.Value, app.dimensionSwitch.Items{2})
                    for i = 1 : size(database.featureNorm)
                        if isequal(file, database.nameList{i, 1})
                            orgIndex = i;
                            break;
                        end
                    end
                    if orgIndex == 0
                        errordlg('please choose the right file!','Error');
                        return;
                    end
                    QueryFeat = database.featureNorm(orgIndex, :);
                else
                    net = load(app.modelPath);
                    % 利用matconvnet官方提供的方法提取特征向量
                    if size(oriImg, 3) == 3  % 是彩色
                        im_ = single(oriImg);
                        im_ = imresize(im_, net.meta.normalization.imageSize(1:2));
                        im_ = im_ - net.meta.normalization.averageImage;
                        res = vl_simplenn(net, im_);
                        
                        featVec = res(20).x;
                        featVec = featVec(:);
                    else  % Oxford5k素材中好像有黑白图片，要考虑
                        im_ = single(repmat(oriImg,[1 1 3]));
                        im_ = imresize(im_, net.meta.normalization.imageSize(1:2));
                        im_ = im_ - net.meta.normalization.averageImage;
                        res = vl_simplenn(net, im_);
                        
                        featVec = res(20).x;
                        featVec = featVec(:);
                    end
                    QueryFeat = featVec';
                    parfor i = 1:size(QueryFeat, 1)  % 对待检索图片特征向量归一化
                        if(norm(QueryFeat(i, :)) ~= 0)
                            QueryFeat(i ,:) = QueryFeat(i, :) ./ norm(QueryFeat(i, :));
                        end
                    end
                end
                
                
                % 进行检索
                [n,~] = size(database.featureNorm);
                score = zeros(n, 1);
                
                score = (QueryFeat * database.featureNorm')';  % 向量内积
                [~, index] = topkrows(score, retrievalNum);  % 取Top K
                rankedImageID = index;  % 记录排序后的矩阵下标对应情况
                
                output = uint8(zeros(imgL, imgW, 3, retrievalNum)); % 存储输出图像
                for i = 1 : retrievalNum
                    imgName = database.nameList{rankedImageID(i, 1), 1};  % 取前num个图像
                    im = imread(imgName);
                    im = imresize(im, [imgL imgW]);
                    output(:, :, :, i) = im;
                end
                app.timeTextArea.Value = num2str(toc) + " s";
                if retrievalNum > 3
                    app.ImageRank1.Visible = 0;
                    app.ImageRank2.Visible = 0;
                    app.ImageRank3.Visible = 0;
                    app.promptLabel.Visible = 1;
                    figure('color', 'white');
                    montage(output(:, :, :, (1 : retrievalNum)));
                    title('Search result');
                else
                    app.ImageRank1.ImageSource = output(:, :, :, 1);
                    app.ImageRank2.ImageSource = output(:, :, :, 2);
                    app.ImageRank3.ImageSource = output(:, :, :, 3);
                end
            end
        end

        % Button pushed function: extractionBtn
        function extractionBtnButtonPushed(app, event)
            % 功能：提取特征向量
            % 选择保存路径
            [file, path] = uiputfile('*.mat', 'Dataset Saver', './Data/');
            if file
                app.datasetPath = fullfile(path, file);
                % 提取特征前准备网络和相应文件
                if isequal(app.matFileCheckBox, 0)
                    progressWindow = uiprogressdlg(app.ContentbasedImageRetrievalUIFigure, 'Title','Compiling the net',...
                        'Message','Just a few seconds...',...
                        'Indeterminate','on');
                    run ./matconvnet-1.0-beta25/matlab/vl_compilenn;
                    close(progressWindow);
                end
                run ./matconvnet-1.0-beta25/matlab/vl_setupnn;
                app.setupFlag = 1;
                addpath(app.filePath);
                net = load(app.modelPath);
                % 读取图片并提取特征向量
                dataFile = dir(app.filePath);
                nameList = {dataFile(~[dataFile.isdir]).name};  % 记录目录下所有文件名
                nameList = nameList';  % 转置，将其变成 N * 1的矩阵，便于运算
                numOfImg = length(nameList);  % 总图片数目
                feature = [];  % 记录特征
                % 设置进度条
                progressWindow = uiprogressdlg(app.ContentbasedImageRetrievalUIFigure, 'Title','Extracting all the images',...
                    'Message','Just a few seconds...',...
                    'Indeterminate', 'on');
                parfor i = 1 : numOfImg
                    oriImg = imread(nameList{i, 1});
                    if size(oriImg, 3) == 3  % 是彩色图片
                        im_ = single(oriImg) ; %rgb图片转成single
                        im_ = imresize(im_, net.meta.normalization.imageSize(1:2));
                        im_ = im_ - net.meta.normalization.averageImage;
                        res = vl_simplenn(net, im_);  %轻量级的线性拓扑结构的CNN包装器
                        featVec = res(20).x;  %取第19层输出
                        
                        featVec = featVec(:);
                        feature = [feature; featVec'];  %记录每张图特征向量
                    else  % Oxford5k素材中好像有黑白图片，要考虑
                        im_ = single(repmat(oriImg, [1 1 3])); %黑白图片复制成3个深度再转成single
                        im_ = imresize(im_, net.meta.normalization.imageSize(1:2));
                        im_ = im_ - net.meta.normalization.averageImage;
                        res = vl_simplenn(net, im_); %轻量级的线性拓扑结构的CNN包装器
                        featVec = res(20).x;  %取第19层输出
                        
                        featVec = featVec(:);
                        feature = [feature; featVec'];  %记录每张图特征向量
                    end
                end
                
                if isequal(app.dimensionSwitch.Value, app.dimensionSwitch.Items{2})
                    %PCA降维，文献中推荐为128维
                    [coeff] = pca(feature);
                    feature = feature * coeff(:, 1 : 128);
                end
                
                % 利用欧几里得范数归一化，所有数据统一映射，提高精度
                parfor i = 1:size(feature, 1)
                    if(norm(feature(i, :)) ~= 0)
                        featureNorm(i ,:) = feature(i, :) ./ norm(feature(i, :));
                    end
                end
                close(progressWindow);
                
                save(app.datasetPath, 'featureNorm', 'nameList', '-v7.3');  % 生成mat文件
                app.extractionLabel.Text = 'Extraction completed.';
                app.extractionLamp.Color = [0.00, 1.00, 0.00];
                app.retrievalBtn.Enable = 1;
            end
        end

        % Value changed function: dimensionSwitch
        function dimensionSwitchValueChanged(app, event)
            value = app.dimensionSwitch.Value;
            if isequal(value, app.dimensionSwitch.Items{2})
                message = sprintf('If the dimension of features is 128, you are exclusively allowed to select the image from the chosen dataset for query.');
                uialert(app.ContentbasedImageRetrievalUIFigure, message,'Attention', 'Icon', 'info');
            end
        end

        % Button pushed function: CalculateButton
        function CalculateButtonPushed(app, event)
            value = app.mAPDropDown.Value;
            if isequal(value, app.mAPDropDown.Items{1})
                app.mAPModelName = './Data/HolidaysFeaturesNorm.mat';
                app.resultName = './Data/resultFileHolidays4096D.dat';
                app.pathNeeded = './Holidays/';
                %getResultFileHolidays(app);
                result = calMAPHolidays(app);
                
            elseif isequal(value, app.mAPDropDown.Items{2})
                app.mAPModelName = './Data/Holidays128D.mat';
                app.resultName = './Data/resultFileHolidays128D.dat';
                app.pathNeeded = './Holidays/';
                %getResultFileHolidays(app);
                result = calMAPHolidays(app);
            elseif isequal(value, app.mAPDropDown.Items{3})
                app.mAPModelName = './Data/OxfordFeaturesNorm.mat';
                app.resultName = './Data/resultFileOxford4096D.dat';
                app.pathNeeded = './Oxford5k/';
                %getResultFileOxford(app);
                result = calMAPOxford(app);
            else
                app.mAPModelName = './Data/Oxford128D.mat';
                app.resultName = './Data/resultFileOxford128D.dat';
                app.pathNeeded = './Oxford5k/';
                %getResultFileOxford(app);
                result = calMAPOxford(app);
            end
            app.mAPTextArea.Value = num2str(result) + "%";
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.ContentbasedImageRetrievalUIFigure = uifigure('Visible', 'off');
            app.ContentbasedImageRetrievalUIFigure.Name = 'Content-based Image Retrieval';
            app.retrievalBtn = uibutton(app.ContentbasedImageRetrievalUIFigure, 'push');
            app.retrievalBtn.ButtonPushedFcn = createCallbackFcn(app, @retrievalBtnButtonPushed, true);
            app.retrievalBtn.Enable = 'off';
            app.retrievalBtn.Text = 'Start retrieval';
            app.Image = uiimage(app.ContentbasedImageRetrievalUIFigure);
            app.TrainningModelPanel = uipanel(app.ContentbasedImageRetrievalUIFigure);
            app.TrainningModelPanel.Title = 'Trainning Model';
            app.ModelTextAreaLabel = uilabel(app.TrainningModelPanel);
            app.ModelTextAreaLabel.Text = 'Model';
            app.ModelTextArea = uitextarea(app.TrainningModelPanel);
            app.ModelTextArea.Value = {'First, select a training model.'};
            app.modelSelectorBtn = uibutton(app.TrainningModelPanel, 'push');
            app.modelSelectorBtn.ButtonPushedFcn = createCallbackFcn(app, @modelSelectorBtnPushed, true);
            app.modelSelectorBtn.Text = 'Select A Model';
            app.DatasetPanel = uipanel(app.ContentbasedImageRetrievalUIFigure);
            app.DatasetPanel.Title = 'Dataset';
            app.DatasetTextAreaLabel = uilabel(app.DatasetPanel);
            app.DatasetTextAreaLabel.Text = 'Dataset';
            app.DatasetTextArea = uitextarea(app.DatasetPanel);
            app.DatasetTextArea.Value = {'Then, select the dataset.'};
            app.dataSelectorBtn = uibutton(app.DatasetPanel, 'push');
            app.dataSelectorBtn.ButtonPushedFcn = createCallbackFcn(app, @dataSelectorBtnButtonPushed, true);
            app.dataSelectorBtn.Enable = 'off';
            app.dataSelectorBtn.Text = 'Select dataset ';
            app.extractionLamp = uilamp(app.DatasetPanel);
            app.extractionBtn = uibutton(app.DatasetPanel, 'push');
            app.extractionBtn.ButtonPushedFcn = createCallbackFcn(app, @extractionBtnButtonPushed, true);
            app.extractionBtn.Enable = 'off';
            app.extractionBtn.Text = 'Extract features';
            app.extractionLabel = uilabel(app.DatasetPanel);
            app.extractionLabel.Text = 'Select the dataset before extraction. ';
            app.DimensionofthefeaturesSwitchLabel = uilabel(app.DatasetPanel);
            app.DimensionofthefeaturesSwitchLabel.Text = 'Dimension of the features';
            app.dimensionSwitch = uiswitch(app.DatasetPanel, 'slider');
            app.dimensionSwitch.Items = {'4096', '128'};
            app.dimensionSwitch.ValueChangedFcn = createCallbackFcn(app, @dimensionSwitchValueChanged, true);
            app.dimensionSwitch.Value = '4096';
            app.ImageForQueryLabel = uilabel(app.ContentbasedImageRetrievalUIFigure);
            app.ImageForQueryLabel.Text = 'Image For Query';
            app.QuestionBeforeSelectingTheDatasetPanel = uipanel(app.ContentbasedImageRetrievalUIFigure);
            app.QuestionBeforeSelectingTheDatasetPanel.Title = 'Question Before Selecting The Dataset';
            app.matFileCheckBox = uicheckbox(app.QuestionBeforeSelectingTheDatasetPanel);
            app.matFileCheckBox.Text = 'The dataset is a mat file.';
            app.compileCheckBox = uicheckbox(app.QuestionBeforeSelectingTheDatasetPanel);
            app.compileCheckBox.Text = 'The network has been compiled.';
            app.ResultPanel = uipanel(app.ContentbasedImageRetrievalUIFigure);
            app.ResultPanel.Title = 'Result';
            app.promptLabel = uilabel(app.ResultPanel);
            app.promptLabel.Visible = 'off';
            app.promptLabel.Text = 'The result will be presented in the pop-up window! ';
            app.ImageRank1 = uiimage(app.ResultPanel);
            app.ImageRank2 = uiimage(app.ResultPanel);
            app.ImageRank3 = uiimage(app.ResultPanel);
            app.Rank1Label = uilabel(app.ResultPanel);
            app.Rank1Label.Text = 'Rank1';
            app.Rank2Label = uilabel(app.ResultPanel);
            app.Rank2Label.Text = 'Rank2';
            app.Rank3Label = uilabel(app.ResultPanel);
            app.Rank3Label.Text = 'Rank3';
            app.TimeLabel = uilabel(app.ResultPanel);
            app.TimeLabel.Text = 'Time';
            app.timeTextArea = uitextarea(app.ResultPanel);
            app.timeTextArea.Editable = 'off';
            app.RetrievalNumberSpinnerLabel = uilabel(app.ContentbasedImageRetrievalUIFigure);
            app.RetrievalNumberSpinnerLabel.Text = 'Retrieval Number';
            app.retrievalNumberSpinner = uispinner(app.ContentbasedImageRetrievalUIFigure);
            app.retrievalNumberSpinner.Limits = [3 20];
            app.retrievalNumberSpinner.ValueDisplayFormat = '%.0f';
            app.retrievalNumberSpinner.Value = 3;
            app.JianmingLaoLabel = uilabel(app.ContentbasedImageRetrievalUIFigure);
            app.JianmingLaoLabel.Text = '--Jianming Lao';
            app.mAPTextArea = uitextarea(app.ContentbasedImageRetrievalUIFigure);
            app.mAPTextArea.Editable = 'off';
            app.ThemAPofDropDownLabel = uilabel(app.ContentbasedImageRetrievalUIFigure);
            app.ThemAPofDropDownLabel.Text = 'The mAP of';
            app.mAPDropDown = uidropdown(app.ContentbasedImageRetrievalUIFigure);
            app.mAPDropDown.Items = {'Holidays4096D', 'Holidays128D', 'Oxford4096D', 'Oxford128D'};
            app.mAPDropDown.Value = 'Holidays4096D';
            app.CalculateButton = uibutton(app.ContentbasedImageRetrievalUIFigure, 'push');
            app.CalculateButton.ButtonPushedFcn = createCallbackFcn(app, @CalculateButtonPushed, true);
            app.CalculateButton.Text = 'Calculate';
            % Show the figure after all components are created
            app.ContentbasedImageRetrievalUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Content_based_Image_Retrieval

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.ContentbasedImageRetrievalUIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.ContentbasedImageRetrievalUIFigure)
        end
    end
end